classdef NeuroPredictor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        StartButton     matlab.ui.control.Button
        PredictivebrainclassDrABastosFall2022Label  matlab.ui.control.Label
        vulogotopleft   matlab.ui.control.Image
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)

        DataFilePath = "";
        Data = [];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Data = load("data.mat");

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)


            lfpx = app.Data.lfp(251:4250, :, :);
            fs = 1000;
            MaxFreq = 150;
            TimeBins = 4;
            
            FreqPointList = 10:10:150;
            BandLabels = "<Alpha";
            
            for i = 1:length(FreqPointList)-1
            
                if FreqPointList(i) < 10
            
                    BandLabels = [BandLabels, "<Alpha"];
            
                elseif FreqPointList(i) < 30
            
                    BandLabels = [BandLabels, "Beta"];
            
                elseif FreqPointList(i) >30
            
                    BandLabels = [BandLabels, "Gamma"];
            
                end
            
            end
            
            clc;
            
            y = zeros(length(FreqPointList), size(lfpx, 2), TimeBins);
            
            for i = 1:3
                y(:, (i-1)*16+1:i*16, :) = dlLaminarSpectroTemporalFeatures(lfpx(:, (i-1)*16+1:i*16, :), fs, MaxFreq, TimeBins, FreqPointList);
            end
            
            ChannelLabels = [];
            
            for i = 1:size(y, 2)
            
                c1 = mean(mean(y(2:7, i, :))) / mean(mean(y(7:14, i, :)));
                c2 = max(max(y(2:7, i, :))) / max(max(y(7:14, i, :)));
            
                if c1 < 0.84 || c2 < 0.77
            
                    ChannelLabels = [ChannelLabels, "Sup("+string(i)+")"];
            
                elseif c1 > 1.14 || c2 > 1.24
            
                    ChannelLabels = [ChannelLabels, "Deep("+string(i)+")"];
            
                else
            
                    ChannelLabels = [ChannelLabels, "Mid("+string(i)+")"];
            
                end
            
            end

            T = 2;
            imagesc(app.UIAxes, y(:, :, T)');
            xlabel(app.UIAxes, "Band relative power plot for " + string(T) + "th time interval");
            ylabel(app.UIAxes, "Channel index");colorbar(app.UIAxes);
            
            xticklabels(app.UIAxes, BandLabels);
            xticks(app.UIAxes, 1:length(BandLabels));
            yticklabels(app.UIAxes, ChannelLabels);
            yticks(app.UIAxes, 1:length(ChannelLabels));


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {639, 639};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.vulogotopleft = uiimage(app.LeftPanel);
            app.vulogotopleft.ImageSource = fullfile(pathToMLAPP, 'AppData', 'VULogo.png');
            app.PredictivebrainclassDrABastosFall2022Label = uilabel(app.LeftPanel);
            app.PredictivebrainclassDrABastosFall2022Label.Text = {'Vanderbilt University'; 'Dept. of Psychological Sciences '; 'HNXJ@BastosLab'; '2022-'};
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroPredictor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end